classdef layout_creator_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Button_4                  matlab.ui.control.Button
        vButton                   matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        SelectedListBox           matlab.ui.control.ListBox
        SelectedListBoxLabel      matlab.ui.control.Label
        SystemDropDown            matlab.ui.control.DropDown
        SystemDropDownLabel       matlab.ui.control.Label
        UnselectedListBox         matlab.ui.control.ListBox
        UnselectedListBoxLabel    matlab.ui.control.Label
        Button_2                  matlab.ui.control.Button
        SavelayoutfileButton      matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        LightButton               matlab.ui.control.Button
        SelectionModeButtonGroup  matlab.ui.container.ButtonGroup
        AdddeleteButton           matlab.ui.control.RadioButton
        LabelExplorerButton       matlab.ui.control.RadioButton
        ShowunselectedelectrodesCheckBox  matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        layout % Description
        unselectedSensPlot
        sensplot
        numselected
    end

    
    methods (Access = private)
        
        function newlayout_update(app)
            
            % get microstate path
            mspath = microstate.functions.toolbox_path() ; 
            
            % get the type of modality
            str = app.SystemDropDown.Value ;
            
            % Clear the axes
            cla(app.UIAxes);
            view(app.UIAxes,[0,90])
            
            % Decide the modality
            switch str
                case 'EEG (1020/1005)'
                    dir = 'layout';
                    file = 'EEG1005.mat' ; 
                case 'EEG (Biosemi)'
                    dir = 'layout' ; 
                    file = 'biosemi256.mat' ; 
                case 'MEG (CTF151)'
                    dir = 'layout' ; 
                    file = 'CTF151.mat' ; 
                case 'MEG (CTF275)' 
                    dir = 'layout' ; 
                    file = 'CTF275.mat' ; 
                case 'MEG (Neuromag)'
                    dir = 'layout' ; 
                    file = 'neuromag306.mat' ; 
                case 'Source (AAL)'
                    dir = 'atlas' ; 
                    file = 'aal.mat' ; 
                case 'Source (AFNI0)'
                    dir = 'atlas' ; 
                    file = 'afni_brick0.mat' ; 
                case 'Source (AFNI1)'
                    dir = 'atlas' ; 
                    file = 'afni_brick1.mat' ; 
                case 'Source (Brainnetome)'
                    dir = 'atlas' ; 
                    file = 'brainnetome.mat' ; 
                case 'Source (HCP230)'
                    dir = 'atlas' ; 
                    file = 'hcp230.mat' ; 
            end
            
            % make layout structure 
            if strcmp(str,'Source (HCP230)')
                app.layout = load(fullfile(mspath,'+external','HCP230',dir,file)) ;
            else
                app.layout = load(fullfile(mspath,'+external','fieldtrip','template',dir,file)) ;
            end
            if strcmp(dir,'layout')
                app.layout.type = 'sensor' ; 
            elseif strcmp(dir,'atlas')
                app.layout.type = 'source' ; 
            end
            
            switch app.layout.type
                case 'sensor'
                    
                    app.LightButton.Visible=false ; 
                    app.LabelExplorerButton.Text = 'Label Explorer' ; 
                    app.AdddeleteButton.Enable=true ; 
                    
                    app.layout.selected = false(size(app.layout.label)) ;
                    
                    app.numselected=0;
                    
                    % Update the EEG structure
                    app.UnselectedListBox.Items = app.layout.label;
                    
                    % Plot the EEG outline
                    hold(app.UIAxes,'on');
                    xl = -inf ; yl = -inf ; 
                    for i = 1:length(app.layout.outline)
                        plot(app.UIAxes,app.layout.outline{i}(:,1),app.layout.outline{i}(:,2),'k','LineWidth',2); 
                        xl = max(xl,max(abs(app.layout.outline{i}(:,1)))) ; yl = max(yl,abs(max(app.layout.outline{i}(:,2)))) ;  
                    end
                    xl = max(xl,yl) ; 
                    xlim(app.UIAxes,[-xl,xl])
                    ylim(app.UIAxes,[-xl,xl])
                    app.unselectedSensPlot = plot(app.UIAxes,app.layout.pos(:,1),app.layout.pos(:,2),'ok');
                    app.unselectedSensPlot.DataTipTemplate.DataTipRows(2:end) = [] ; 
                    app.unselectedSensPlot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('',app.layout.label) ; 
                    axis(app.UIAxes,'equal'); axis(app.UIAxes,'off') ;
                    app.unselectedSensPlot.ButtonDownFcn=@app.mouseClick;
                    app.sensplot = [] ;
                    
                case 'source'
                    
                    app.LightButton.Visible=true ; 
                    app.LabelExplorerButton.Value = 1 ; 
                    app.LabelExplorerButton.Text = 'MNI Coords' ; 
                    app.AdddeleteButton.Enable=false ; 
                    
                    app.layout.selected = false(size(app.layout.tissuelabel)) ;
                    
                    app.numselected=0;
                    
                    % Update the EEG structure
                    app.UnselectedListBox.Items = app.layout.tissuelabel;
                    
                    % Plot
                    app.layout.patch = load(fullfile(mspath,'+external','fieldtrip','template','anatomy','inflated.mat')) ; 
                    app.layout.patch.facecolor = 'flat' ; 
                    app.layout.patch.facevertexcdata = zeros(size(app.layout.patch.vertices,1),1) ; 
                    app.layout.patch.linestyle = 'none' ; 
                    rng('default') ; app.layout.colormap = [0.8*ones(1,3);repmat(lhsdesign(length(app.layout.tissuelabel),3),2,1)];
                    colormap(app.UIAxes,app.layout.colormap);
                    caxis(app.UIAxes,[0,length(app.layout.tissuelabel)])
                    
                    axis(app.UIAxes,'equal');
                    xlim(app.UIAxes,[-150 150]);
                    ylim(app.UIAxes,[-150 150]);
                    
                    app.unselectedSensPlot = patch(app.UIAxes,app.layout.patch) ; 
%                     app.unselectedSensPlot.ButtonDownFcn=@app.mouseClick1;
                    camlight(app.UIAxes,'headlight') ; lighting(app.UIAxes,'gouraud') ; material(app.UIAxes,'dull')
                    
%                     h.ActionPostCallback = @(x,y) disp('hello') ; 
%                     h.ButtonDownFilter = @app.rotationbuttonfilter ;
                    
            end
            
        end
        
%         function rotationcallback(app,x,y)
% %             c = findobj(app.UIAxes,'type','light') ; 
% %             if isempty(c)
% %                 c = camlight('headlight') ; 
% %             end
% %             c = camlight(c,'headlight') ; 
%         end
%         
%         function res = rotationbuttonfilter(app,clickedObj,event)
%             h = rotate3d(app.UIAxes) ; 
%             res = strcmp(h.Enable,'on') ; 
%         end
%         
        
        
        function mouseClick(app,clickedObj,Hit)

            % Find nearest point to click
            pos = Hit.IntersectionPoint(1,1:2); 
            dx2 = sum((pos-app.layout.pos).^2,2) ; 
            [~,ind] = min(dx2) ; 
            
            % mode
            mode = app.SelectionModeButtonGroup.SelectedObject.Text ; 
            
            switch mode
                case 'Add/delete'
                    % Check whether the click is on a selected or unselected
                    % electrodes
                    switch app.layout.selected(ind)
                        case true % selected
                            % delete from the selected window and plot
                            inds = find(strcmp(app.layout.label{ind},get(app.SelectedListBox,'Items'))) ; 
                            app.SelectedListBox.Items(inds) = [] ;
                            delete(app.sensplot(inds)) ; 
                            app.sensplot(inds) = []; 
                    
                            % set back to visible in unselected window
                            app.layout.selected(ind) = false ; 
                            app.numselected = app.numselected-1;
                            app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
                            
                        case false
                            
                            % Add to selected list
                            app.SelectedListBox.Items(app.numselected+1) = app.layout.label(ind);
                            app.numselected = app.numselected+1;
                
                            % Remove from unselected list
                            app.layout.selected(ind) = true ; 
                    
                            % Plot
                            app.sensplot = [app.sensplot ; plot(app.UIAxes,app.layout.pos(ind,1),app.layout.pos(ind,2),'ok','MarkerFaceColor','g')];
                            app.sensplot(end).ButtonDownFcn = @app.mouseClick ; 
                            app.sensplot(end).DataTipTemplate.DataTipRows(2:end) = [] ; 
                            app.sensplot(end).DataTipTemplate.DataTipRows(1) = dataTipTextRow('',app.layout.label(ind)) ; 
                            app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
                    end
                    
                case 'Label Explorer'
                    
                    datatip(clickedObj,pos(1),pos(2)) ; 
                    
            end
            
        end
        
        
%         function mouseClick1(app,clickedObj,Hit)
%     
% %             h = rotate3d(app.UIAxes) ; 
% %             if strcmp(h.Enable,'on')
% %                 return
% %             end
%             % Find nearest point to click
%             cp = get(app.UIAxes,'CurrentPoint') ; 
%             pos = cp(1,1:3); 
%             dx2 = sum((pos-app.layout.patch.vertices).^2,2) ; 
%             [~,ind] = min(dx2) ; 
%             
%             % mode
%             mode = app.SelectionModeButtonGroup.SelectedObject.Text ; 
%             
%             switch mode
%                 case 'MNI Coords'
%                     
%                     % Display a datatip 
%                     delete(findall(app.UIFigure,'Type','hggroup'));
%                     try
%                         datatip(clickedObj,pos(1),pos(2),pos(3)) ;
%                     catch
%                         datatip(clickedObj,pos(1),pos(2)) ;
%                     end
%                     
%                     lbl = app.layout.tissuelabel{app.layout.tissue(ind)} ;
%                     lbl = join(strsplit(lbl,'_'),'\_') ; lbl = lbl{1} ; 
%                     
%                     % Update the datatip
%                     row = dataTipTextRow(lbl,[],'auto') ;
%                     clickedObj.DataTipTemplate.DataTipRows(2:end) = []; 
%                     clickedObj.DataTipTemplate.DataTipRows(1) = row ; 
%             end
%     
%         end
%         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function app_startupfcn(app)
            clc; % delete
            app.newlayout_update ; 
%             app.clickhdl = @(x,y) mouseClick(app,x,y) ; 
            
            % Make a button click function
            app.unselectedSensPlot.ButtonDownFcn=@app.mouseClick;
            
%             function mouseClick(clickedObj,Hit)
% 
%                 % Find nearest point to click
%                 pos = Hit.IntersectionPoint(1,1:2); 
%                 dx2 = sum((pos-app.layout.pos).^2,2) ; 
%                 [~,ind] = min(dx2) ; 
%                 
%                 % mode
%                 mode = app.SelectionModeButtonGroup.SelectedObject.Text ; 
%                 
%                 switch mode
%                     case 'Add/delete'
%                         % Check whether the click is on a selected or unselected
%                         % electrodes
%                         switch app.layout.selected(ind)
%                             case true % selected
%                                 % delete from the selected window and plot
%                                 inds = find(strcmp(app.layout.label{ind},get(app.SelectedListBox,'Items'))) ; 
%                                 app.SelectedListBox.Items(inds) = [] ;
%                                 delete(app.sensplot(inds)) ; 
%                                 app.sensplot(inds) = []; 
%                         
%                                 % set back to visible in unselected window
%                                 app.layout.selected(ind) = false ; 
%                                 app.numselected = app.numselected-1;
%                                 app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
%                                 
%                             case false
%                                 
%                                 % Add to selected list
%                                 app.SelectedListBox.Items(app.numselected+1) = app.layout.label(ind);
%                                 app.numselected = app.numselected+1;
%                     
%                                 % Remove from unselected list
%                                 app.layout.selected(ind) = true ; 
%                         
%                                 % Plot
%                                 app.sensplot = [app.sensplot ; plot(app.UIAxes,app.layout.pos(ind,1),app.layout.pos(ind,2),'ok','MarkerFaceColor','g')];
%                                 app.sensplot(end).ButtonDownFcn = app.clickhdl ; 
%                                 app.sensplot(end).DataTipTemplate.DataTipRows(2:end) = [] ; 
%                                 app.sensplot(end).DataTipTemplate.DataTipRows(1) = dataTipTextRow('',app.layout.label) ; 
%                                 app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
%                         end
%                         
%                     case 'Label Explorer'
%                         
%                         datatip(clickedObj,pos(1),pos(2)) ; 
%                         
%                 end
%                 
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button_2
        function eeg_addbutton(app, event)
            % Add to selected list
            selected = get(app.UnselectedListBox,'Value'); 
            app.SelectedListBox.Items(app.numselected + (1:length(selected))) = selected;
            app.numselected = app.numselected+length(selected);
            
            % Remove from unselected list
            selectedMode = app.SelectionModeButtonGroup.SelectedObject;
            
            switch app.layout.type
                case 'sensor'
                    for i = 1:length(selected)
                        ind = find(strcmp(selected{i},app.layout.label)) ; 
                        app.layout.selected(ind) = true ; 
                        
                        % Plot
                        app.sensplot = [app.sensplot ; plot(app.UIAxes,app.layout.pos(ind,1),app.layout.pos(ind,2),'ok','MarkerFaceColor','g')];
                        app.sensplot(end).ButtonDownFcn = @app.mouseClick ; 
                        app.sensplot(end).DataTipTemplate.DataTipRows(2:end) = [] ; 
                        app.sensplot(end).DataTipTemplate.DataTipRows(1) = dataTipTextRow('',app.layout.label) ; 
                    end
                    app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
                case 'source'
                    for i = 1:length(selected)
                        ind = find(strcmp(selected{i},app.layout.tissuelabel)) ; 
                        app.layout.selected(ind) = true ; 
                        
                        % Plot
                        idx = app.layout.tissue==ind ; 
                        app.unselectedSensPlot.FaceVertexCData(idx)=ind;
                    end
                    app.UnselectedListBox.Items = app.layout.tissuelabel(~app.layout.selected);
            end


        end

        % Value changed function: ShowunselectedelectrodesCheckBox
        function change_plotUnselected(app, event)
            switch app.layout.type
                case 'sensor'
                    value = app.ShowunselectedelectrodesCheckBox.Value;
                    app.unselectedSensPlot.Visible = value;
                case 'source'
                    value = app.ShowunselectedelectrodesCheckBox.Value;
                    if ~value
                        app.unselectedSensPlot.FaceVertexCData(app.unselectedSensPlot.FaceVertexCData==0)=nan ; 
                    else
                        app.unselectedSensPlot.FaceVertexCData(isnan(app.unselectedSensPlot.FaceVertexCData))=0 ;
                    end
            end
        end

        % Button pushed function: Button_4
        function eeg_delbutton(app, event)
            
            unselected = get(app.SelectedListBox,'Value'); 
            % delete from the selected window and plot
            ind = find(strcmp(unselected,get(app.SelectedListBox,'Items'))) ; 
            app.SelectedListBox.Items(ind) = [] ;
            
            switch app.layout.type
                case 'sensor'
                    delete(app.sensplot(ind)) ; 
                    app.sensplot(ind) = []; 
                        
                    % set back to visible in unselected window
                    ind = find(strcmp(unselected,app.layout.label)) ; 
                    app.layout.selected(ind) = false ; 
        
                    app.numselected = app.numselected-1;
                    app.UnselectedListBox.Items = app.layout.label(~app.layout.selected);
                case 'source'
                    % set back to visible in unselected window
                    ind = find(strcmp(unselected,app.layout.tissuelabel)) ; 
                    app.layout.selected(ind) = false ; 
                    
                    idx = app.layout.tissue==ind ; 
                    value = app.ShowunselectedelectrodesCheckBox.Value;
                    if ~value
                        app.unselectedSensPlot.FaceVertexCData(idx)=nan ; 
                    else
                        app.unselectedSensPlot.FaceVertexCData(idx)=0 ;
                    end
                    app.numselected = app.numselected-1;
                    app.UnselectedListBox.Items = app.layout.tissuelabel(~app.layout.selected);
            end
            
        end

        % Selection changed function: SelectionModeButtonGroup
        function selectionMode(app, event)
            selectedButton = app.SelectionModeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Add/delete'
%                     for i = 1:length(sensplot)
%                         sensplot(i).ButtonDownFcn = clickhdl ; 
%                     end
%                     unselectedSensPlot.ButtonDownFcn = clickhdl ; 
                    dc = datacursormode(app.UIFigure);
                    dc.Enable = 'off' ; 
                    
                case 'Label Explorer'
%                     for i = 1:length(sensplot)
%                         sensplot(i).ButtonDownFcn = '' ; 
%                     end
%                     unselectedSensPlot.ButtonDownFcn = '' ;  
                    dc = datacursormode(app.UIFigure);
                    dc.Enable = 'on' ; 
            
            end
            
            
        end

        % Button pushed function: Button_3
        function eeg_reorderUp(app, event)
            itemList = get(app.SelectedListBox,'Items') ;
            
            if length(itemList) == 1
                return
            end
            
            selected = get(app.SelectedListBox,'Value') ; 
            indselected = find(strcmp(selected,itemList)) ; 
            
            if indselected == 1
                return
            end
            
            neword = [1:indselected-2 , indselected , indselected-1 , (indselected+1):length(itemList)] ; 
            itemList = itemList(neword) ; 
            
            set(app.SelectedListBox,'Items',itemList);
            
            if strcmp(app.layout.type,'sensor')
                app.sensplot = app.sensplot(neword);
            end
        end

        % Button pushed function: vButton
        function eeg_reorderDown(app, event)
            itemList = get(app.SelectedListBox,'Items') ;
            
            if length(itemList) == 1
                return
            end
            
            selected = get(app.SelectedListBox,'Value') ; 
            indselected = find(strcmp(selected,itemList)) ; 
            
            if indselected == length(itemList)
                return
            end
            
            neword = [1:indselected-1 , indselected+1 , indselected , (indselected+2):length(itemList)] ; 
            itemList = itemList(neword) ; 
            
            set(app.SelectedListBox,'Items',itemList);
            
            if strcmp(app.layout.type,'sensor')
                app.sensplot = app.sensplot(neword);
            end
        end

        % Value changed function: SystemDropDown
        function newsystem_select(app, event)
            value = app.SystemDropDown.Value;
            
            selected = length(app.SelectedListBox.Items) ; 
            
            if selected>0
                docontinue = uiconfirm(app.UIFigure,'Warning: Changing system will clear current selections, continue?','Confirm system change','Icon','warning') ;
            else
                docontinue = true ; 
            end
            
            if ~docontinue
                return
            end
            
            cla(app.UIAxes) ; 
            
            app.sensplot = [] ; 
            app.SelectedListBox.Items(:) = [];
            app.newlayout_update ; 
            
        end

        % Button pushed function: LightButton
        function new_camlight(app, event)
            delete(findall(app.UIFigure,'type','light'))
            camlight(app.UIAxes,'headlight') ; lighting(app.UIAxes,'gouraud') ; material(app.UIAxes,'dull')
        end

        % Button pushed function: SavelayoutfileButton
        function saveLayout(app, event)

            layout = struct ; 
            switch app.layout.type
                case 'sensor'
                    layout.label = app.SelectedListBox.Items(:) ; 
                    for i = 1:length(layout.label)
                        ind = find(strcmp(layout.label{i},app.layout.label)) ; 
                        layout.pos(i,:) = app.layout.pos(ind,:) ; 
                    end
                    layout.outline = app.layout.outline ; 
                case 'source'
                    layout.label = app.SelectedListBox.Items(:) ;
%                     layout.patch = rmfield(app.layout.patch,'facevertexcdata') ; 
                    for i = 1:length(layout.label)
                        ind = find(strcmp(layout.label{i},app.layout.tissuelabel)) ; 
                        layout.Vertices{i,1} = find(app.layout.tissue == ind) ; 
                        layout.Centroid = app.layout.centroid(ind,:) ; 
                    end 
            end
            [file,path] = uiputfile('.mat','Save layout file') ; 
            save(fullfile(path,file),'-struct','layout') ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SavelayoutfileButton = uibutton(app.LeftPanel, 'push');
            app.SavelayoutfileButton.ButtonPushedFcn = createCallbackFcn(app, @saveLayout, true);
            app.SavelayoutfileButton.Text = 'Save layout file';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @eeg_addbutton, true);
            app.Button_2.Text = '+';
            app.UnselectedListBoxLabel = uilabel(app.LeftPanel);
            app.UnselectedListBoxLabel.Text = 'Unselected';
            app.UnselectedListBox = uilistbox(app.LeftPanel);
            app.UnselectedListBox.Items = {};
            app.UnselectedListBox.Multiselect = 'on';
            app.UnselectedListBox.Value = {};
            app.SystemDropDownLabel = uilabel(app.LeftPanel);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.LeftPanel);
            app.SystemDropDown.Items = {'EEG (1020/1005)', 'EEG (Biosemi)', 'MEG (CTF151)', 'MEG (CTF275)', 'MEG (Neuromag)', 'Source (AAL)', 'Source (AFNI0)', 'Source (AFNI1)', 'Source (Brainnetome)', 'Source (HCP230)'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @newsystem_select, true);
            app.SystemDropDown.Value = 'EEG (1020/1005)';
            app.SelectedListBoxLabel = uilabel(app.LeftPanel);
            app.SelectedListBoxLabel.Text = 'Selected';
            app.SelectedListBox = uilistbox(app.LeftPanel);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Value = {};
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @eeg_reorderUp, true);
            app.Button_3.Text = '^';
            app.vButton = uibutton(app.LeftPanel, 'push');
            app.vButton.ButtonPushedFcn = createCallbackFcn(app, @eeg_reorderDown, true);
            app.vButton.Text = 'v';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @eeg_delbutton, true);
            app.Button_4.Text = '-';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.ShowunselectedelectrodesCheckBox = uicheckbox(app.RightPanel);
            app.ShowunselectedelectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @change_plotUnselected, true);
            app.ShowunselectedelectrodesCheckBox.Text = 'Show unselected electrodes';
            app.ShowunselectedelectrodesCheckBox.Value = true;
            app.SelectionModeButtonGroup = uibuttongroup(app.RightPanel);
            app.SelectionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectionMode, true);
            app.SelectionModeButtonGroup.Title = 'Selection Mode';
            app.LabelExplorerButton = uiradiobutton(app.SelectionModeButtonGroup);
            app.LabelExplorerButton.Text = 'Label Explorer';
            app.LabelExplorerButton.Value = true;
            app.AdddeleteButton = uiradiobutton(app.SelectionModeButtonGroup);
            app.AdddeleteButton.Text = 'Add/delete';
            app.LightButton = uibutton(app.RightPanel, 'push');
            app.LightButton.ButtonPushedFcn = createCallbackFcn(app, @new_camlight, true);
            app.LightButton.Text = 'Light';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layout_creator_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @app_startupfcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end